classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        OccupancyPanel        matlab.ui.container.Panel
        TimerCounterLabel     matlab.ui.control.Label
        MapBuildingLamp       matlab.ui.control.Lamp
        DiscoveredPercentage  matlab.ui.control.Gauge
        Panel_3               matlab.ui.container.Panel
        AutoexploremodeLabel  matlab.ui.control.Label
        AutoexploreSwitch     matlab.ui.control.Switch
        ConnectionLamp        matlab.ui.control.Lamp
        ConnectionLampLabel   matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        BtnFullStop           matlab.ui.control.StateButton
        BtnLeft               matlab.ui.control.StateButton
        BtnBackward           matlab.ui.control.StateButton
        BtnRight              matlab.ui.control.StateButton
        BtnForward            matlab.ui.control.StateButton
        BtnCW                 matlab.ui.control.StateButton
        BtnCCW                matlab.ui.control.StateButton
        BtnConnect            matlab.ui.control.Button
        cameraAxes            matlab.ui.control.UIAxes
        mapOccupancyAxes      matlab.ui.control.UIAxes
        mapTrajectoryAxes     matlab.ui.control.UIAxes
    end

    properties(Constant)

    end
    properties (Access = private)
        
        statusOffColor = [0.5, 0.5, 0.5]; % Color for inactive status
        statusOnColor = 'green'; % Color for active status
        baseControl % Base control object for robot movements
        connection  % Instance of RobotConnection to manage connection to CoppeliaSim
        laserScanner % Instance of LaserScanner to retrieve data from the robot's LIDAR sensor
        slamHandler % Instance of SLAMHandler to manage SLAM operations and map-building
        
        lidarSlam % SLAM algorithm object
        worldMap  % Occupancy map generated by SLAM
        timerToUpdateSLAMdata % Timer to update SLAM data periodically
        timerToUpdateMap % Timer to update the occupancy map
        timerToMoveTheRobot % Timer to handle the robot's movements
        timerUpdateElapsedTime % Timer to track elapsed time

        intialPosition % Initial position of the robot
        timerRecalculatePath % Timer to recalculate the robot's path
        maximumRange = 9; % Maximum LIDAR range
        currentPoseOfTheRobot % Stores the current robot pose
        plannedPath % Stores the planned path for the robot
        optimisedPath % Stores the optimized path
        purePursuitController % Controller for path following using Pure Pursuit

        goalRadius = 0.1; % Radius around the goal to consider it reached
        targetReached = false; % Flag to indicate whether the robot has reached its target
        minFrontDist % Stores the minimum distance detected in front of the robot

        timerToUpdateCamera % Timer to periodically update the camera feed
        camHandle % Handle for the camera in CoppeliaSim
        hImage % Handle for the displayed camera image in UIAxes

        returnToStart % Flag to indicate if the robot should return to its start position
        floorXMin = -5.000; % Minimum X boundary of the floor
        floorXMax = 5.000;  % Maximum X boundary of the floor
        floorYMin = -5.000; % Minimum Y boundary of the floor
        floorYMax = 0;      % Maximum Y boundary of the floor
        
        TotalElapsedTime % Total time elapsed
        StartTime % Time when the timer started
    end
    
    properties (Access = public)
       
    end
    
methods (Access = private)


    % Method to initialize various components of the app (e.g., robot control, SLAM, camera)
    function initialiseComponents(app)
        % Retrieves handles for the robot and its wheels, and initializes the BaseControl object.
        % Also sets up the SLAM algorithm and initializes the occupancy map.
        %
        % This method performs the following:
        % - Retrieves the handles for the robot and wheels from CoppeliaSim.
        % - Initializes the BaseControl object to control robot movement.
        % - Sets up the SLAMHandler to manage the SLAM algorithm and map-building process.
        % - Initializes the camera feed from the robot's camera in CoppeliaSim.
        
        % Retrieve the handle for the robot object from CoppeliaSim
        [~, robotHandle] = app.connection.sim.simxGetObjectHandle(app.connection.clientID, '/Robot', app.connection.sim.simx_opmode_blocking);

        % Retrieve wheel handles from CoppeliaSim (use correct object names)
        [rightMotor, ~] = app.connection.getHandle('/Robot/rightMotor');
        [leftMotor, ~] = app.connection.getHandle('/Robot/leftMotor');
        
        % Initialize the BaseControl object for controlling the robot
        wheelHandles = [rightMotor, leftMotor];
        wheelRadius = 0.02;
        app.baseControl = BaseControl(app.connection, robotHandle, wheelHandles, wheelRadius);
        
        % Initialize SLAM parameters and the SLAMHandler object
        maxRange = app.maximumRange; % Maximum LIDAR range
        mapResolution = 20;          % Map resolution: 20 cells per meter (5cm precision)
        loopClosureThreshold = 210;
        loopClosureSearchRadius = 8;
        app.laserScanner = LaserScanner(app.connection); % Initialize the laser scanner
        app.slamHandler = SLAMHandler(app.laserScanner, mapResolution, maxRange, loopClosureThreshold, loopClosureSearchRadius, app.mapTrajectoryAxes, app.mapOccupancyAxes);
        
        % Initialize occupancy map
        app.initialiseOccupancyMap();

        % Initialize the camera and start streaming
        [app.camHandle, ~] = app.connection.getHandle('/Camera');
        [~, resolution, ~] = app.connection.sim.simxGetVisionSensorImage2(app.connection.clientID, app.camHandle, 0, app.connection.sim.simx_opmode_streaming);
        app.hImage = imshow(zeros(resolution(2), resolution(1), 3), 'Parent', app.cameraAxes);

        app.returnToStart = false; % Initialize the return-to-start flag
    end

    % Method to initialize buttons and UI elements
    function initialiseButtons(app)
        % Configures movement buttons (forward, backward, left, right, etc.) with unique tags.
        % Initializes UI components such as lamps, labels, and gauges to their default states.
        %
        % Assigns tags to the movement buttons and sets their default state as disabled.
        % Initializes timers and other UI components.

        app.toggleControls(); % Enable or disable controls based on connection status
        app.MapBuildingLamp.Color = app.statusOffColor; % Turn off the map-building lamp
        
        % Assign unique tags to movement buttons for easy identification
        app.BtnForward.Tag = 'FORWARD';
        app.BtnBackward.Tag = 'BACKWARD';
        app.BtnLeft.Tag = 'LEFT';
        app.BtnRight.Tag = 'RIGHT';
        app.BtnCW.Tag = 'CLOCKWISE';
        app.BtnCCW.Tag = 'COUNTER_CLOCKWISE';
        app.BtnFullStop.Tag = 'STOP';

        % Initialize time and discovery percentage for the exploration task
        app.StartTime = []; % Initialize start time
        app.TotalElapsedTime = 0; % Reset total elapsed time
        app.DiscoveredPercentage.Value = 0; % Set initial value of exploration gauge to 0
    end

    % Method to initialize the occupancy map
    function initialiseOccupancyMap(app)
        % Retrieves the first laser scan data and builds the initial occupancy map.
        %
        % This method initializes the occupancy map using the robot's initial laser scan data.
        % It updates the SLAMHandler with the first scan and builds the occupancy map based on
        % the scanned data.
        
        [cartesianData, currentPose, ~, ~, ~, ~] = app.laserScanner.getScannerData(); % Get laser scan data
        lidarScanObject = lidarScan(cartesianData); % Create a lidar scan object
        addScan(app.lidarSlam, lidarScanObject, currentPose); % Add the scan to SLAM
        [scansSLAM, poses] = scansAndPoses(app.lidarSlam); % Retrieve SLAM scans and poses
        app.worldMap = buildMap(scansSLAM, poses, app.lidarSlam.MapResolution, app.lidarSlam.MaxLidarRange); % Build the occupancy map
        app.currentPoseOfTheRobot = currentPose; % Store the robot's initial pose
    end

    % Method to start all timers used in the app
    function startAllTimers(app)
        % Starts timers responsible for updating SLAM data, occupancy map, robot movement, and camera feed.
        % Also starts the SLAMHandler if it is not already updating.
        
        if strcmp(app.timerToUpdateSLAMdata.Running, 'off')
            start(app.timerToUpdateSLAMdata);
        end
        if strcmp(app.timerToUpdateMap.Running, 'off')
            start(app.timerToUpdateMap);
        end
        if strcmp(app.timerRecalculatePath.Running, 'off')
            start(app.timerRecalculatePath);
        end
        if strcmp(app.timerToUpdateCamera.Running, 'off')
            start(app.timerToUpdateCamera);
        end
        if strcmp(app.timerUpdateElapsedTime.Running, 'off')
            start(app.timerUpdateElapsedTime);
        end
        if ~app.slamHandler.isUpdating
            app.slamHandler.startUpdating(); % Start SLAMHandler updates if not already running
        end
    end

    % Method to stop all timers
    function stopAllTimers(app)
        % Stops all active timers and updates the total elapsed time.
        % Also stops the SLAMHandler if it is updating.
        
        timers = timerfindall; % Find all active timers
        
        % If the session was running, calculate the total elapsed time
        if ~isempty(app.StartTime)
            thisSessionTime = toc(app.StartTime);
            app.TotalElapsedTime = thisSessionTime + app.TotalElapsedTime; % Update total elapsed time
            app.StartTime = []; % Reset the start time
        end

        % Stop and delete each timer
        if ~isempty(timers)
            stop(timers);
            disp('All timers have been stopped.');
        else
            disp('No active timers found.');
        end
        
        % Stop SLAMHandler if it is running
        if ~isempty(app.slamHandler) && app.slamHandler.isUpdating
            app.slamHandler.stopUpdating();
        end
        
        app.AutoexploreSwitch.Value = 'Off'; % Turn off auto-explore mode
    end

    function toggleMovementButtons(app, state)
        % Toggle the movement buttons (enable or disable) based on the input state.
        % The input 'state' can be 'on' to enable buttons or 'off' to disable them.
    
        % Create a list of all movement buttons
        movementButtons = {app.BtnForward, app.BtnBackward, app.BtnLeft, app.BtnRight, app.BtnCW, app.BtnCCW, app.BtnFullStop};
        
        % Loop through all movement buttons and set their enable status based on 'state'
        for i = 1:length(movementButtons)
            movementButtons{i}.Enable = state;
        end
        
        % Also enable or disable the auto-explore switch and its label
        app.AutoexploreSwitch.Enable = state;
        app.AutoexploremodeLabel.Enable = state;
        
        % If the state is 'off', ensure the auto-explore switch is set to 'Off'
        if strcmp(state, "off")
            app.AutoexploreSwitch.Value = 'Off';
        end
    end

    function toggleBuildMapButtons(app)
        % Toggle the map-building lamp based on the state of the map update timer.
        %
        % This method checks whether the timer for updating the occupancy map is running.
        % If the timer is running, the map-building lamp will be turned on (green).
        % If the timer is not running, the lamp will be turned off (grey).
    
        if strcmp(app.timerToUpdateMap.Running, 'off')
            % If the map update timer is off, turn off the map-building lamp
            app.MapBuildingLamp.Color = app.statusOffColor; % Set the lamp color to grey (off)
        else
            % If the map update timer is running, turn on the map-building lamp
            app.MapBuildingLamp.Color = app.statusOnColor;  % Set the lamp color to green (on)
        end
    end

    % Method to toggle the control buttons based on connection status
    function toggleControls(app)
        % Enables or disables the movement and exploration buttons depending on the connection status.
        %
        % If the robot is connected to CoppeliaSim, enable the movement controls.
        % If disconnected, disable the controls and reset the UI.
        
        if app.connection.clientID == -1
            app.BtnConnect.Text = 'Connect'; % Update the button label
            app.BtnConnect.Icon = 'icons/Connect.png'; % Set connection icon
            app.ConnectionLamp.Color = app.statusOffColor; % Set the connection lamp color to off
            app.toggleMovementButtons("off"); % Disable movement buttons
        else
            app.BtnConnect.Text = 'Disconnect'; % Update the button label
            app.BtnConnect.Icon = 'icons/Disconnect.png'; % Set disconnection icon
            app.ConnectionLamp.Color = app.statusOnColor; % Set the connection lamp color to on
            app.toggleMovementButtons("on"); % Enable movement buttons
        end
    end

    % Method to update the SLAM trajectory visualization on the UIAxes
    function updateTrajectoryMap(app)
        % Updates the trajectory map by displaying the robot's path and SLAM-generated map.
        % If a planned path is available, it overlays the planned path on the map.
        
        axes(app.mapTrajectoryAxes); % Set the UIAxes for the SLAM trajectory
        show(app.lidarSlam, 'Parent', app.mapTrajectoryAxes); % Show the SLAM map and trajectory
        hold(app.mapTrajectoryAxes, 'on'); % Hold the plot to overlay other elements
        
        % Plot the planned path if available
        if ~isempty(app.plannedPath)
            plot(app.mapTrajectoryAxes, app.plannedPath(:, 1), app.plannedPath(:, 2), 'r-', 'LineWidth', 2); % Red line for the planned path
        end
        
        title(app.mapTrajectoryAxes, 'SLAM Map and Trajectory'); % Set the title for the plot
        hold(app.mapTrajectoryAxes, 'off'); % Release the plot hold
        drawnow; % Refresh the display
    end

    % Method to update the occupancy map visualization
    function updateOccupancyMap(app)
        % Updates the occupancy grid map based on the current state of the world map.
        % Also displays the robot's current position and any planned/optimized paths.
        
        axes(app.mapOccupancyAxes); % Set the UIAxes for the occupancy map
        cla(app.mapOccupancyAxes);  % Clear any previous plots
        show(app.worldMap, 'Parent', app.mapOccupancyAxes); % Display the updated occupancy map
        hold(app.mapOccupancyAxes, 'on'); % Hold the plot for additional elements
        
        % Plot the robot's current position
        if ~isempty(app.currentPoseOfTheRobot)
            plot(app.mapOccupancyAxes, app.currentPoseOfTheRobot(1), app.currentPoseOfTheRobot(2), 'bo', 'MarkerSize', 8, 'MarkerFaceColor', 'b');
        end
        
        % Plot the planned path (if available)
        if ~isempty(app.plannedPath)
            plot(app.mapOccupancyAxes, app.plannedPath(:, 1), app.plannedPath(:, 2), 'r-', 'LineWidth', 2);
        end

        % Plot the optimized path (if available)
        if ~isempty(app.optimisedPath)
            plot(app.mapOccupancyAxes, app.optimisedPath(:, 1), app.optimisedPath(:, 2), 'g:', 'LineWidth', 2); % Green dotted line for optimized path
        end
        
        % Set zoom around the robot's position
        if ~isempty(app.currentPoseOfTheRobot)
            zoomCenterX = app.intialPosition(1) + 4; % Set zoom center X
            zoomCenterY = app.intialPosition(2) - 1; % Set zoom center Y
            zoomWidth = 11; % Zoom width
            zoomHeight = 11; % Zoom height
            xlim(app.mapOccupancyAxes, [zoomCenterX - zoomWidth/2, zoomCenterX + zoomWidth/2]); % Set X-axis limits
            ylim(app.mapOccupancyAxes, [zoomCenterY - zoomHeight/2, zoomCenterY + zoomHeight/2]); % Set Y-axis limits
        end
        
        hold(app.mapOccupancyAxes, 'off'); % Release the plot hold
        title(app.mapOccupancyAxes, 'Occupancy Grid Map Built Using Lidar SLAM');
        drawnow; % Refresh the display
    end

    % Method to move the robot along a planned path
    function moveTheRobot(app)
        % Moves the robot using the Pure Pursuit controller based on the planned path.
        % Continuously updates the robot's position and stops when the target is reached.
        
        if ~app.targetReached && ~isempty(app.plannedPath)
            % Get the current pose of the robot
            robotPosition = app.currentPoseOfTheRobot;
            
            % Compute control inputs (linear and angular velocities) using Pure Pursuit
            [v, omega] = app.purePursuitController(robotPosition);
            
            % Move the robot using the calculated velocities
            app.baseControl.moveRobot(v, omega, app.minFrontDist);
            
            % Check if the robot has reached the goal
            distanceToGoal = norm(robotPosition(1:2) - app.purePursuitController.Waypoints(end, 1:2));
            if distanceToGoal <= app.goalRadius
                app.targetReached = true; % Set the flag indicating the goal is reached
                app.plannedPath = []; % Clear the planned path
                app.baseControl.stop(); % Stop the robot
                stop(app.timerToMoveTheRobot); % Stop the robot movement timer
                
                % If the return-to-start flag is set, stop all timers
                if app.returnToStart
                    app.stopAllTimers();
                end
            end
        end
    end

    % Method to calculate and plan the robot's trajectory to the closest unexplored area
    function calculateTrajectory(app)
        % Plans a path to the closest unexplored area on the occupancy map.
        % If the robot has not reached the goal, it updates the robot's path using A* path planning.
        
        [x, y] = app.findClosestUnexplored(); % Find the closest unexplored point
        robotX = app.currentPoseOfTheRobot(1); % Current X position of the robot
        robotY = app.currentPoseOfTheRobot(2); % Current Y position of the robot
        goalPosition = [robotX, robotY] - [x, y]; % Set goal position
        
        if ~isempty(goalPosition) && ~app.targetReached
            inflateRadius = 0.4; % Radius for map inflation to avoid obstacles
            inflatedMap = copy(app.worldMap); % Copy the current occupancy map
            inflate(inflatedMap, inflateRadius); % Inflate the map to avoid obstacles
            
            startPosition = [robotX, robotY]; % Define the robot's starting position
            pathPlanner = plannerAStarGrid(inflatedMap); % A* path planner instance
            
            % Check if the start and goal positions are valid
            isStartValid = checkOccupancy(inflatedMap, startPosition) < 0.5;
            isGoalValid = checkOccupancy(inflatedMap, goalPosition) < 0.5 || app.returnToStart;
            
            % If both start and goal positions are valid, calculate the path
            if isStartValid && isGoalValid
                try
                    firstPath = plan(pathPlanner, startPosition, goalPosition, 'world');
                    if ~isempty(firstPath)
                        % Optimize and update the planned path
                        app.optimisedPath = optimizePath(firstPath, inflatedMap);
                        app.plannedPath = firstPath;
                        app.purePursuitController.Waypoints = firstPath;
                        
                        % Start the robot movement timer if not already running
                        if strcmp(app.timerToMoveTheRobot.Running, 'off')
                            start(app.timerToMoveTheRobot);
                        end
                    else
                        disp('No valid path found.');
                        app.baseControl.moveRobot(0, 0, 0); % Stop the robot
                    end
                catch exception
                    disp(exception.message);
                    app.baseControl.moveRobot(0, 0, 0); % Stop the robot in case of an error
                    app.stopAllTimers();
                end
            else
                disp('Invalid start or goal position.');
            end
        else
            app.baseControl.moveRobot(0, 0, 0); % Stop the robot if no valid goal is found
            app.stopAllTimers();
        end
    end

    % Method to find the closest unexplored point on the occupancy map
    function [x, y] = findClosestUnexplored(app)
        % Scans the occupancy map to find the closest unexplored cell (value close to 0.5).
        % Returns the coordinates of the closest unexplored point.
        
        if app.returnToStart
            x = app.intialPosition(1);
            y = app.intialPosition(2);
            return;
        end
        
        map = app.worldMap;
        occMatrix = getOccupancy(map); % Get the occupancy matrix (probabilities)
        unexploredValue = 0.5; % Define the threshold for unexplored areas
        
        % Get the robot's position in grid coordinates
        robotX = app.currentPoseOfTheRobot(1);
        robotY = app.currentPoseOfTheRobot(2);
        
        % Initialize minimum distance and coordinates for the closest unexplored point
        minDist = inf;
        x = NaN;
        y = NaN;
        
        % Loop through the grid cells of the occupancy matrix to find unexplored areas
        [mapHeight, mapWidth] = size(occMatrix);
        for i = 1:mapHeight
            for j = 1:mapWidth
                occVal = occMatrix(i, j);
                if abs(occVal - unexploredValue) < 0.01 % Check if cell is unexplored
                    gridCoords = grid2world(app.worldMap, [i, j]); % Convert grid coordinates to world coordinates
                    worldX = gridCoords(1);
                    worldY = gridCoords(2);
                    
                    % Check if the point is within the floor boundaries
                    if worldX >= app.floorXMin && worldX <= app.floorXMax && worldY >= app.floorYMin && worldY <= app.floorYMax
                        % Calculate the distance from the robot to the unexplored point
                        dist = sqrt((i - robotX)^2 + (j - robotY)^2);
                        
                        % Update the minimum distance and closest unexplored coordinates
                        if dist < minDist
                            minDist = dist;
                            x = round(worldX, 4); % Round coordinates for precision
                            y = round(worldY, 4);
                            app.targetReached = false; % Reset the goal-reached flag
                        end
                    end
                end
            end
        end
    end

    % Method to update SLAM data by retrieving new laser scans
    function updateSLAMdata(app)
        % Retrieves new laser scan data and updates the SLAM algorithm.
        % Adds the scan to the occupancy map and updates the robot's position.
        
        maxrange = app.maximumRange; % Maximum range for LIDAR
        [cartesianData, currentPose, ~, ~, app.minFrontDist, ~] = app.laserScanner.getScannerData(); % Get new laser data
        
        if ~isempty(cartesianData) && ~isempty(currentPose)
            lidarScanObject = lidarScan(cartesianData); % Create a LIDAR scan object
            addScan(app.lidarSlam, lidarScanObject, currentPose); % Add the scan to the SLAM object
            
            % Remove invalid data and insert valid LIDAR data into the occupancy map
            minRange = 0.1;
            maxRange = app.maximumRange;
            validData = removeInvalidData(lidarScanObject, 'RangeLimits', [minRange maxRange]);
            insertRay(app.worldMap, currentPose, validData, maxrange); % Update the occupancy map
            
            app.currentPoseOfTheRobot = currentPose; % Update the robot's current position
            
            % Store the initial position if it hasn't been set
            if isempty(app.intialPosition)
                app.intialPosition = currentPose;
            end
        end
    end

    % Method to update the camera feed by retrieving images from CoppeliaSim
    function updateCameraFeed(app)
        % Retrieves the current frame from the robot's camera and updates the display in the UI.
        
        [res, ~, image] = app.connection.sim.simxGetVisionSensorImage2(app.connection.clientID, app.camHandle, 0, app.connection.sim.simx_opmode_buffer); % Get image data
        
        if res == app.connection.sim.simx_return_ok
            set(app.hImage, 'CData', image); % Update the image displayed in UIAxes
            drawnow; % Refresh the display
        end
    end

    % Method to update the elapsed time and percentage of area explored
    function updateElapsedTime(app)
        % Updates the elapsed time since the start of the exploration and calculates the percentage of the explored area.
        
        mapData = getOccupancy(app.worldMap); % Get the occupancy matrix
        
        % Define thresholds for identifying free and occupied cells
        thresholdFree = 0.3;     % Anything below this is considered free
        thresholdOccupied = 0.6; % Anything above this is considered occupied
        
        % Get map resolution and total number of cells
        resolution = app.worldMap.Resolution; % Meters per cell
        totalCells = 10 * 5 * (resolution^2); % Total number of cells in the map
        totalArea = totalCells * (resolution^2); % Total map area in square meters
        
        % Count the number of explored cells (either free or occupied)
        exploredCells = mapData < thresholdFree | mapData > thresholdOccupied;
        exploredCount = sum(exploredCells(:)); % Number of explored cells
        exploredArea = exploredCount * (resolution^2); % Area covered by explored cells
        
        % Calculate the percentage of explored area
        exploredPercentage = (exploredArea / totalArea) * 100;
        app.DiscoveredPercentage.Value = exploredPercentage; % Update the gauge
        
        % Timer handling
        if isempty(app.StartTime)
            app.StartTime = tic; % Record the start time
        end
        
        % Calculate total elapsed time (in seconds)
        thisSession = toc(app.StartTime);
        elapsedTime = thisSession + app.TotalElapsedTime;
        
        % Convert elapsed time to minutes and seconds
        minutes = floor(mod(elapsedTime, 3600) / 60);
        seconds = floor(mod(elapsedTime, 60));
        
        % Update the label with the formatted elapsed time
        app.TimerCounterLabel.Text = sprintf('%02d:%02d', minutes, seconds);
        
        % If the explored percentage exceeds 96%, stop the exploration
        if exploredPercentage >= 96
            uialert(app.UIFigure, 'Finished exploring the area.', 'Status');
            app.baseControl.stop(); % Stop the robot
            app.returnToStart = true; % Set the return-to-start flag
            app.stopAllTimers(); % Stop all active timers
        end
    end

    
end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            % Add these folders to the MATLAB path
            addpath(fullfile(pwd,"API/"));
            addpath(fullfile(pwd,"ControlAndManagement/"));
            addpath(fullfile(pwd,"SensorsAndMapping/"));
                       

            % Initialize the connection to CoppeliaSim
            app.connection = RobotConnection();
            
            % Initialize UI buttons and their states
            app.initialiseButtons();
            
            % Timer to periodically update SLAM data
            app.timerToUpdateSLAMdata = timer('ExecutionMode', 'fixedRate', ...
                                       'Period', 0.3, ... % Update SLAM data every 0.3 seconds
                                       'TimerFcn', @(~,~) app.updateSLAMdata());
        
            % Timer to move the robot along its path
            app.timerToMoveTheRobot = timer('ExecutionMode', 'fixedRate', ...
                                       'Period', 2, ... % Move the robot every 2 seconds
                                       'TimerFcn', @(~,~) app.moveTheRobot());
        
            % Timer to update the occupancy map
            app.timerToUpdateMap = timer('ExecutionMode', 'fixedRate', ...
                                       'Period', 1, ... % Update the occupancy map every 1 second
                                       'TimerFcn', @(~,~) app.updateOccupancyMap());
        
            % Timer to recalculate the robot's path
            app.timerRecalculatePath = timer('ExecutionMode', 'fixedRate', ...
                                       'Period', 3, ... % Recalculate path every 3 seconds
                                       'TimerFcn', @(~,~) app.calculateTrajectory());
        
            % Timer to update the camera feed
            app.timerToUpdateCamera = timer('ExecutionMode', 'fixedRate', ...
                            'Period', 0.1, ...  % Update the camera feed every 0.1 seconds (10 FPS)
                            'TimerFcn', @(~,~) app.updateCameraFeed());
        
            % Timer to update the elapsed time and exploration status
            app.timerUpdateElapsedTime = timer('ExecutionMode', 'fixedRate', ...
                                        'Period', 1, ...  % Update every 1 second
                                        'TimerFcn', @(~,~) app.updateElapsedTime());
        
            % Initialize the SLAM algorithm with specified parameters
            maxRange = app.maximumRange; % Maximum LIDAR range in meters
            mapResolution = 10; % Occupancy map resolution: 10 cells per meter
            app.lidarSlam = lidarSLAM(mapResolution, maxRange); % Create the SLAM object
            app.lidarSlam.LoopClosureThreshold = 210; % Set the loop closure detection threshold
            app.lidarSlam.LoopClosureSearchRadius = 8; % Set the search radius for loop closure
        
            % Initialize the Pure Pursuit controller for path-following
            app.purePursuitController = controllerPurePursuit;
            app.purePursuitController.LookaheadDistance = 0.6;  % Set the lookahead distance to 0.6 meters
            app.purePursuitController.DesiredLinearVelocity = 0.2;  % Set the desired linear velocity to 0.2 m/s
            app.purePursuitController.MaxAngularVelocity = 0.6;  % Set the maximum angular velocity to 0.6 rad/s

        end

        % Button pushed function: BtnConnect
        function connectButtonPushed(app, event)
            % Check if the simulation is already running
            if ~app.connection.isSimulationRunning
                % Attempt to connect to CoppeliaSim
                success = app.connection.connect();
                if success
                    % If the connection is successful, initialize the components (robot, SLAM, etc.)
                    app.initialiseComponents();
                else
                    % If connection fails, alert the user with an error message
                    uialert(app.UIFigure, 'Connection failed. Something went wrong, please check the logs', 'Status');
                end
            else
                % If already connected, disconnect from the simulation
                app.connection.disconnect();
                % Stop all running timers when disconnected
                app.stopAllTimers();
            end
            
            % Toggle the state of the controls (buttons, switches) based on the connection status
            app.toggleControls();
        end

        % Value changed function: BtnBackward, BtnCCW, BtnCW, BtnForward, 
        % ...and 3 other components
        function movementValueChanged(app, event)
            % Get the current state of the pressed movement button
            value = event.Source.Value;
            
            % Get the type of movement (FORWARD, BACKWARD, LEFT, etc.) from the button's tag
            movementType = event.Source.Tag;
            
            % Create a list of all movement buttons (excluding the Full Stop button)
            movementButtons = {app.BtnForward, app.BtnBackward, app.BtnLeft, app.BtnRight, app.BtnCW, app.BtnCCW};
            
            % Identify the pressed button from the event source
            pressedButton = event.Source;
            
            % Filter out the pressed button from the list of movement buttons
            remainingButtons = movementButtons(~cellfun(@(btn) isequal(btn, pressedButton), movementButtons));
            
            % Set the value of all other buttons (except the pressed one) to 0 (unpressed state)
            for i = 1:length(remainingButtons)
                remainingButtons{i}.Value = 0;
            end
        
            % If the Full Stop button was pressed, stop all robot movement and timers
            if strcmp(movementType, 'STOP')
                app.stopAllTimers();        % Stop all active timers
                app.baseControl.stop();      % Stop robot movement
                app.BtnFullStop.Value = 0;   % Reset the Full Stop button value
            else
                % Ensure all relevant timers are running for SLAM, map updates, and camera feed
                if strcmp(app.timerToUpdateSLAMdata.Running, 'off')
                    start(app.timerToUpdateSLAMdata);
                end
                if strcmp(app.timerToUpdateMap.Running, 'off')
                    start(app.timerToUpdateMap);
                end
                if strcmp(app.timerToUpdateCamera.Running, 'off')
                    start(app.timerToUpdateCamera);
                end
                if strcmp(app.timerUpdateElapsedTime.Running, 'off')
                    start(app.timerUpdateElapsedTime);
                end
                if ~app.slamHandler.isUpdating
                    app.slamHandler.startUpdating(); % Start SLAM updates if not already running
                end
                
                % Handle movement for buttons other than Full Stop
                if value == 1  % If the button is pressed (value == 1)
                    % Move the robot based on the selected movement type (e.g., FORWARD, BACKWARD)
                    app.baseControl.move(app.baseControl.WheelMovements.(movementType));
                else
                    % Stop the robot if the button is unpressed
                    app.baseControl.stop();
                end
            end
            
            % Update the map-building lamp to reflect the current state
            app.toggleBuildMapButtons();
        end

        % Value changed function: AutoexploreSwitch
        function autoexploreSwitchValueChanged(app, event)
            % This function is triggered when the auto-explore switch is toggled
            %
            % If the auto-exploration switch is turned on (the timers for path recalculation are off),
            % the exploration is started by turning on all relevant timers (SLAM updates, map updates, etc.).
            % If the switch is turned off, the exploration is stopped, and all timers are stopped.
        
            % Check if the path recalculation timer is running
            if strcmp(app.timerRecalculatePath.Running, 'off')
                % Start all timers to begin auto-exploration
                app.startAllTimers();
                disp('Exploration started.');
            else
                % Stop the exploration process by stopping all timers
                disp('Exploration stopped.');
                app.stopAllTimers();
            end
            
            % Toggle the map-building lamp status based on whether the timers are running
            app.toggleBuildMapButtons();
        end

        % Close request function: UIFigure
        function AppCloseRequested(app, event)
            % This function is called when the user attempts to close the app window
            %
            % It ensures that all timers are stopped and the simulation connection is properly disconnected
            % before the app is closed. This helps to safely clean up resources and avoid leaving the
            % simulation running in the background.
        
            % Stop all active timers before closing the app
            app.stopAllTimers();
            
            % If the simulation is currently running, disconnect from CoppeliaSim
            if app.connection.isSimulationRunning
                app.connection.disconnect();
            end
            
            % Delete the app to close the user interface
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @AppCloseRequested, true);
            app.mapTrajectoryAxes = uiaxes(app.UIFigure);
            title(app.mapTrajectoryAxes, 'Trajectory map')
            app.mapTrajectoryAxes.XTick = [];
            app.mapTrajectoryAxes.YTick = [];
            app.mapOccupancyAxes = uiaxes(app.UIFigure);
            title(app.mapOccupancyAxes, 'Occupancy map')
            app.mapOccupancyAxes.XTick = [];
            app.mapOccupancyAxes.YTick = [];
            app.cameraAxes = uiaxes(app.UIFigure);
            title(app.cameraAxes, 'Camera')
            app.cameraAxes.XTick = [];
            app.cameraAxes.YTick = [];
            app.BtnConnect = uibutton(app.UIFigure, 'push');
            app.BtnConnect.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.BtnConnect.Icon = fullfile(pathToMLAPP, 'icons', 'Connect.png');
            app.BtnConnect.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.BtnCCW = uibutton(app.Panel, 'state');
            app.BtnCCW.ValueChangedFcn = createCallbackFcn(app, @movementValueChanged, true);
            app.BtnCCW.Enable = 'off';
            app.BtnCCW.Icon = fullfile(pathToMLAPP, 'icons', 'CCW.png');
            app.BtnCCW.Text = '';
            app.BtnCW = uibutton(app.Panel, 'state');
            app.BtnCW.ValueChangedFcn = createCallbackFcn(app, @movementValueChanged, true);
            app.BtnCW.Enable = 'off';
            app.BtnCW.Icon = fullfile(pathToMLAPP, 'icons', 'CW.png');
            app.BtnCW.Text = '';
            app.BtnForward = uibutton(app.Panel, 'state');
            app.BtnForward.ValueChangedFcn = createCallbackFcn(app, @movementValueChanged, true);
            app.BtnForward.Enable = 'off';
            app.BtnForward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowUp.png');
            app.BtnForward.Text = '';
            app.BtnRight = uibutton(app.Panel, 'state');
            app.BtnRight.ValueChangedFcn = createCallbackFcn(app, @movementValueChanged, true);
            app.BtnRight.Enable = 'off';
            app.BtnRight.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowRight.png');
            app.BtnRight.Text = '';
            app.BtnBackward = uibutton(app.Panel, 'state');
            app.BtnBackward.ValueChangedFcn = createCallbackFcn(app, @movementValueChanged, true);
            app.BtnBackward.Enable = 'off';
            app.BtnBackward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowDown.png');
            app.BtnBackward.Text = '';
            app.BtnLeft = uibutton(app.Panel, 'state');
            app.BtnLeft.ValueChangedFcn = createCallbackFcn(app, @movementValueChanged, true);
            app.BtnLeft.Enable = 'off';
            app.BtnLeft.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowLeft.png');
            app.BtnLeft.Text = '';
            app.BtnFullStop = uibutton(app.Panel, 'state');
            app.BtnFullStop.ValueChangedFcn = createCallbackFcn(app, @movementValueChanged, true);
            app.BtnFullStop.Enable = 'off';
            app.BtnFullStop.Text = '';
            app.BtnFullStop.Icon = fullfile(pathToMLAPP, 'icons', 'Stop.png');
            app.ConnectionLampLabel = uilabel(app.UIFigure);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.UIFigure);
            app.Panel_3 = uipanel(app.UIFigure);
            app.AutoexploreSwitch = uiswitch(app.Panel_3, 'slider');
            app.AutoexploreSwitch.ValueChangedFcn = createCallbackFcn(app, @autoexploreSwitchValueChanged, true);
            app.AutoexploreSwitch.Enable = 'off';
            app.AutoexploremodeLabel = uilabel(app.Panel_3);
            app.AutoexploremodeLabel.Enable = 'off';
            app.AutoexploremodeLabel.Text = 'Auto explore mode';
            app.OccupancyPanel = uipanel(app.UIFigure);
            app.OccupancyPanel.Title = 'Occupancy %';
            app.DiscoveredPercentage = uigauge(app.OccupancyPanel, 'circular');
            app.MapBuildingLamp = uilamp(app.OccupancyPanel);
            app.TimerCounterLabel = uilabel(app.OccupancyPanel);
            app.TimerCounterLabel.Text = '00:00';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end